classdef Kv < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ModelCalibrationKineticsModelingResultsLabel  matlab.ui.control.Label
        ComputerModelLabel              matlab.ui.control.Label
        ExperimentalProtocolDataLabel   matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        TabGroupCalibration             matlab.ui.container.TabGroup
        ModelCalibrationTab             matlab.ui.container.Tab
        ModelParametersImportButton     matlab.ui.control.Button
        ModelParametersSaveButton       matlab.ui.control.Button
        ManualCalibrationButton         matlab.ui.control.Button
        AutomaticCalibrationRunButton   matlab.ui.control.Button
        Kv42TabCalib                    matlab.ui.container.Tab
        Kv15Kv21K2PTabCalib             matlab.ui.container.Tab
        CenterPanel                     matlab.ui.container.Panel
        TabGroupComputerModel           matlab.ui.container.TabGroup
        ModelOutputTab                  matlab.ui.container.Tab
        SelectVoltageDropDown           matlab.ui.control.DropDown
        SelectVoltageDropDownLabel      matlab.ui.control.Label
        UIAxesModelIndv                 matlab.ui.control.UIAxes
        UIAxesModelSum                  matlab.ui.control.UIAxes
        Kv42Tab                         matlab.ui.container.Tab
        IncludeKto                      matlab.ui.control.CheckBox
        LabelModel1                     matlab.ui.control.Label
        p2p3p5KtoEditField3             matlab.ui.control.NumericEditField
        p2p3p5KtoEditField2             matlab.ui.control.NumericEditField
        p2p3p5KtoEditField1             matlab.ui.control.NumericEditField
        p14KtoEditField2                matlab.ui.control.NumericEditField
        p14KtoEditField1                matlab.ui.control.NumericEditField
        p13KtoEditField2                matlab.ui.control.NumericEditField
        p13KtoEditField1                matlab.ui.control.NumericEditField
        p12KtoEditField2                matlab.ui.control.NumericEditField
        p12KtoEditField1                matlab.ui.control.NumericEditField
        p11KtoEditField2                matlab.ui.control.NumericEditField
        p11KtoEditField1                matlab.ui.control.NumericEditField
        p10KtoEditField2                matlab.ui.control.NumericEditField
        p10KtoEditField1                matlab.ui.control.NumericEditField
        p9KtoEditField2                 matlab.ui.control.NumericEditField
        p9KtoEditField1                 matlab.ui.control.NumericEditField
        p8KtoEditField2                 matlab.ui.control.NumericEditField
        p8KtoEditField1                 matlab.ui.control.NumericEditField
        p7KtoEditField2                 matlab.ui.control.NumericEditField
        p7KtoEditField1                 matlab.ui.control.NumericEditField
        p6EditField_8                   matlab.ui.control.NumericEditField
        p6KtoEditField7                 matlab.ui.control.NumericEditField
        p6KtoEditField6                 matlab.ui.control.NumericEditField
        p6KtoEditField5                 matlab.ui.control.NumericEditField
        p6KtoEditField4                 matlab.ui.control.NumericEditField
        p6KtoEditField3                 matlab.ui.control.NumericEditField
        p6KtoEditField2                 matlab.ui.control.NumericEditField
        p6KtoEditField1                 matlab.ui.control.NumericEditField
        p2p5KtoEditField                matlab.ui.control.NumericEditField
        p1p4KtoEditField2               matlab.ui.control.NumericEditField
        p1p4KtoEditField1               matlab.ui.control.NumericEditField
        p2p3KtoEditField3               matlab.ui.control.NumericEditField
        p2p3KtoEditField2               matlab.ui.control.NumericEditField
        p2p3KtoEditField1               matlab.ui.control.NumericEditField
        p2KtoEditField                  matlab.ui.control.NumericEditField
        p1KtoEditField2                 matlab.ui.control.NumericEditField
        p1KtoEditField1                 matlab.ui.control.NumericEditField
        fKto2                           matlab.ui.control.NumericEditField
        fKto1                           matlab.ui.control.NumericEditField
        GKtopEditField                  matlab.ui.control.NumericEditField
        GKtoEditField                   matlab.ui.control.NumericEditField
        IktofEq1                        matlab.ui.control.Image
        IktofEq2                        matlab.ui.control.Image
        Kv15Kv21K2PTab                  matlab.ui.container.Tab
        IncludeKss                      matlab.ui.control.CheckBox
        IncludeKslow2                   matlab.ui.control.CheckBox
        IncludeKslow1                   matlab.ui.control.CheckBox
        p3Kslow1EditField4              matlab.ui.control.NumericEditField
        p3Kslow1EditField3              matlab.ui.control.NumericEditField
        p4KssEditField                  matlab.ui.control.NumericEditField
        p3KssEditField                  matlab.ui.control.NumericEditField
        p2KssEditField                  matlab.ui.control.NumericEditField
        p1KssEditField2                 matlab.ui.control.NumericEditField
        p1KssEditField1                 matlab.ui.control.NumericEditField
        Image                           matlab.ui.control.Image
        LabelModel23                    matlab.ui.control.Label
        LabelModel22                    matlab.ui.control.Label
        LabelModel21                    matlab.ui.control.Label
        p3Kslow2EditField               matlab.ui.control.NumericEditField
        p2Kslow2EditField               matlab.ui.control.NumericEditField
        p1Kslow2EditField               matlab.ui.control.NumericEditField
        Ikslow2Eq                       matlab.ui.control.Image
        p13Kslow1EditField              matlab.ui.control.NumericEditField
        p12Kslow1EditField              matlab.ui.control.NumericEditField
        p11Kslow1EditField2             matlab.ui.control.NumericEditField
        p11Kslow1EditField1             matlab.ui.control.NumericEditField
        p10Kslow1EditField              matlab.ui.control.NumericEditField
        p9Kslow1EditField               matlab.ui.control.NumericEditField
        p8Kslow1EditField               matlab.ui.control.NumericEditField
        p7Kslow1EditField2              matlab.ui.control.NumericEditField
        p7Kslow1EditField1              matlab.ui.control.NumericEditField
        p6Kslow1EditField               matlab.ui.control.NumericEditField
        p5Kslow1EditField2              matlab.ui.control.NumericEditField
        p5Kslow1EditField1              matlab.ui.control.NumericEditField
        p4Kslow1EditField               matlab.ui.control.NumericEditField
        p3Kslow1EditField2              matlab.ui.control.NumericEditField
        p3Kslow1EditField1              matlab.ui.control.NumericEditField
        p2Kslow1EditField2              matlab.ui.control.NumericEditField
        p2Kslow1EditField1              matlab.ui.control.NumericEditField
        p1Kslow1EditField               matlab.ui.control.NumericEditField
        Ikslow1Eq2                      matlab.ui.control.Image
        Ikslow1Eq1                      matlab.ui.control.Image
        LeftPanel                       matlab.ui.container.Panel
        ExperimentalDataPatchclampCurrentRecordingsPanel  matlab.ui.container.Panel
        DatauploadButton                matlab.ui.control.Button
        UIAxesExpData                   matlab.ui.control.UIAxes
        ProtocolPanel                   matlab.ui.container.Panel
        UpdateButton                    matlab.ui.control.Button
        ClampingvoltagemaxmVEditField   matlab.ui.control.NumericEditField
        ClampingvoltagemaxmVEditFieldLabel  matlab.ui.control.Label
        ClampingvoltagestepmVEditField  matlab.ui.control.NumericEditField
        ClampingvoltagestepmVEditFieldLabel  matlab.ui.control.Label
        NernstpotentialmVEditField      matlab.ui.control.NumericEditField
        NernstpotentialmVEditFieldLabel  matlab.ui.control.Label
        ClampingtimemsEditField         matlab.ui.control.NumericEditField
        ClampingtimemsEditFieldLabel    matlab.ui.control.Label
        ClampingvoltageminmVEditField   matlab.ui.control.NumericEditField
        ClampingvoltageminmVEditFieldLabel  matlab.ui.control.Label
        HoldingpotentialmVEditField     matlab.ui.control.NumericEditField
        HoldingpotentialmVEditFieldLabel  matlab.ui.control.Label
        HoldingtimemsEditField          matlab.ui.control.NumericEditField
        HoldingtimemsEditFieldLabel     matlab.ui.control.Label
        UIAxesProtocol                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % time space
        t
        hold_t
        pulse_t
        tt

        % voltage space
        volts
        num_volts
        volt_idx

        % experimental data
        exp_t
        exp_yksum_set_orig
        exp_yksum_set
        exp_volts
        exp_num_volts
        exp_idx

        % currents
        yksum_set
        ykto_set
        ykslow1_set
        ykslow2_set
        ykss_set
        yk1_set

        % current flags to be included
        ykto_idx
        ykslow1_idx
        ykslow2_idx
        ykss_idx
        yk1_idx
        
        % highlighting flag
        highlight_idx
    end
    
    methods (Access = private)
        function [t, hold_t, pulse_t] = gen_time_space(app)
            hold_len = app.HoldingtimemsEditField.Value;
            time_len = hold_len + app.ClampingtimemsEditField.Value;
            
            hold_t = 0:hold_len;
            pulse_t = (hold_len+1):time_len;
            pulse_t_shift = pulse_t - pulse_t(1);
            
            t = [hold_t, pulse_t];
            pulse_t = pulse_t_shift;
        end

        function [volts, num_volts] = gen_volt_space(app)
            minv = app.ClampingvoltageminmVEditField.Value;
            stepv = app.ClampingvoltagestepmVEditField.Value;
            maxv = app.ClampingvoltagemaxmVEditField.Value;
            
            if stepv == 0
                volts = minv;
            else
                volts = minv:stepv:maxv;
            end
            num_volts = length(volts);
        end
        
        function y = gen_ikto(app, v)
            if app.ykto_idx == 0
                y = zeros(length(app.t), 1);
            else
                param = zeros(17, 1);
                param(1) = app.p1KtoEditField1.Value;
                param(2) = app.p2KtoEditField.Value;
                param(3) = app.p2p3KtoEditField1.Value - param(2);
                param(4) = param(1) - app.p1p4KtoEditField1.Value;
                param(5) = param(2) - app.p2p5KtoEditField.Value;
                param(6) = app.p6KtoEditField1.Value;
                param(7) = app.p7KtoEditField1.Value;
                param(8) = app.p8KtoEditField1.Value;
                param(9) = app.p9KtoEditField1.Value;
                param(10) = app.p10KtoEditField1.Value;
                param(11) = app.p11KtoEditField1.Value;
                param(12) = app.p12KtoEditField1.Value;
                param(13) = app.p13KtoEditField1.Value;
                param(14) = app.p14KtoEditField1.Value;
                param(15) = app.fKto1.Value;
                param(16) = app.GKtoEditField.Value;
                param(17) = app.GKtopEditField.Value;
                
                y = ikto(param, app.HoldingpotentialmVEditField.Value, ...
                    v, app.tt, app.NernstpotentialmVEditField.Value);                
            end
        end
        
        function y = gen_ikslow1(app, v)
            if app.ykslow1_idx == 0
                y = zeros(length(app.t), 1);
            else
                param = zeros(13,1);
                
                % shared
                param(1) = app.p1Kslow1EditField.Value;
                param(2) = app.p2Kslow1EditField1.Value;
                param(3) = app.p3Kslow1EditField1.Value;
                param(4) = app.p4Kslow1EditField.Value;
                param(5) = app.p5Kslow1EditField1.Value;
                param(6) = app.p6Kslow1EditField.Value;
                param(7) = app.p7Kslow1EditField1.Value;
                param(8) = app.p8Kslow1EditField.Value;
                
                % unique
                param(9) = app.p9Kslow1EditField.Value;
                param(10) = app.p10Kslow1EditField.Value;
                param(11) = app.p11Kslow1EditField1.Value;
                param(12) = app.p12Kslow1EditField.Value;
                param(13) = app.p13Kslow1EditField.Value;
                
                y = ikslow1(param, app.HoldingpotentialmVEditField.Value, ...
                    v, app.tt, app.NernstpotentialmVEditField.Value);                
            end            
        end
        
        function y = gen_ikslow2(app, v)
            if app.ykslow2_idx == 0
                y = zeros(length(app.t), 1);
            else
                param = zeros(11,1);
                
                % shared
                param(1) = app.p1Kslow1EditField.Value;
                param(2) = app.p2Kslow1EditField1.Value;
                param(3) = app.p3Kslow1EditField1.Value;
                param(4) = app.p4Kslow1EditField.Value;
                param(5) = app.p5Kslow1EditField1.Value;
                param(6) = app.p6Kslow1EditField.Value;
                param(7) = app.p7Kslow1EditField1.Value;
                param(8) = app.p8Kslow1EditField.Value;
                
                % unique
                param(9) = app.p1Kslow2EditField.Value;
                param(10) = app.p2Kslow2EditField.Value;
                param(11) = app.p3Kslow2EditField.Value;
                
                y = ikslow2(param, app.HoldingpotentialmVEditField.Value, ...
                    v, app.tt, app.NernstpotentialmVEditField.Value);                
            end
        end
        
        function y = gen_ikss(app, v)
            if app.ykss_idx == 0
                y = zeros(length(app.t), 1);
            else
                param = zeros(7,1);
                
                % shared
                param(1) = app.p1Kslow1EditField.Value;
                param(2) = app.p3Kslow1EditField1.Value;
                param(3) = app.p4Kslow1EditField.Value;
                
                % unique
                param(4) = app.p1KssEditField1.Value;
                param(5) = app.p2KssEditField.Value;
                param(6) = app.p3KssEditField.Value;
                param(7) = app.p4KssEditField.Value;
                
                y = ikss(param, app.HoldingpotentialmVEditField.Value, ...
                    v, app.tt, app.NernstpotentialmVEditField.Value);                
            end
        end
      
        function yset = gen_currents_over_volts(app, current_generator)
            % wrapper function looping over voltages
            yset = zeros(length(app.t), app.num_volts);
            for i=1:app.num_volts
                y = current_generator(app, app.volts(i));
                if any(isnan(yset))
                    % warning message for NaN
                    app.WarnAppNan = warn_message_nan();
                else
                    yset(:,i) = y;
                end
            end
        end

        function update_sum_current(app)
            for i=1:app.num_volts
                app.yksum_set(:,i) = app.ykto_set(:,i) + app.ykslow1_set(:,i) + ...
                    app.ykslow2_set(:,i) + app.ykss_set(:,i) + app.yk1_set(:,i);
            end
        end

        function plot_protocol(app)
            yhold = ones(length(app.hold_t), 1)*app.HoldingpotentialmVEditField.Value;
            ypulse = ones(length(app.pulse_t), 1)*app.volts(1);
            y = [yhold; ypulse];
            plot(app.UIAxesProtocol, app.t, y, 'LineWidth',1.5)
            
            hold(app.UIAxesProtocol, "on")
            for i=2:app.num_volts
                ypulse = ones(length(app.pulse_t), 1)*app.volts(i);
                y = [yhold; ypulse];
                plot(app.UIAxesProtocol, app.t, y, 'LineWidth',1.5)
            end
            hold(app.UIAxesProtocol, "off")            
        end
    
        function plot_currents_onevolt(app, volt_idx, highlight_idx)
            % check given voltage is out of voltage range of exp_volts
            if app.exp_idx == true
                if ~ismember(app.volts(volt_idx), app.exp_volts)
                    volt_idx = 1;
                    app.SelectVoltageDropDown.Value = app.SelectVoltageDropDown.Items(1);
                    app.WarnAppExpVolt = warn_message_exp_volt(app);
                end
            end
            
            % MiddleRight; individual K+ currents for one voltage
            plot_list = [];
            legend_list = [];
            
            % figure out first current to be plotted
            current_idx_set = [app.ykto_idx, app.ykslow1_idx, app.ykslow2_idx, app.ykss_idx, app.yk1_idx];
            active_current_idx = find(current_idx_set == 1);
            
            % first plot before hold on
            if isempty(active_current_idx)
                plot(app.UIAxesModelIndv, app.t, zeros(length(app.t), 1))
            else
                switch active_current_idx(1)
                    case 1
                        plot_list = [plot_list, plot(app.UIAxesModelIndv, app.t, app.ykto_set(:,volt_idx))];
                        legend_list = [legend_list, "I_{Kto}"];
                    case 2
                        plot_list = [plot_list, plot(app.UIAxesModelIndv, app.t, app.ykslow1_set(:,volt_idx))];
                        legend_list = [legend_list, "I_{Kslow1}"];
                    case 3
                        plot_list = [plot_list, plot(app.UIAxesModelIndv, app.t, app.ykslow2_set(:,volt_idx))];
                        legend_list = [legend_list, "I_{Kslow2}"];
                    case 4
                        plot_list = [plot_list, plot(app.UIAxesModelIndv, app.t, app.ykss_set(:,volt_idx))];
                        legend_list = [legend_list, "I_{Kss}"];
                    case 5
                        plot_list = [plot_list, plot(app.UIAxesModelIndv, app.t, app.yk1_set(:,volt_idx))];
                        legend_list = [legend_list, "I_{K1}"];                        
                end
                
                hold(app.UIAxesModelIndv, "on")
                for i = 2:length(active_current_idx)
                    switch active_current_idx(i)
                        case 1
                            plot_list = [plot_list, plot(app.UIAxesModelIndv, app.t, app.ykto_set(:,volt_idx))];
                            legend_list = [legend_list, "I_{Kto}"];
                        case 2
                            plot_list = [plot_list, plot(app.UIAxesModelIndv, app.t, app.ykslow1_set(:,volt_idx))];
                            legend_list = [legend_list, "I_{Kslow1}"];
                        case 3
                            plot_list = [plot_list, plot(app.UIAxesModelIndv, app.t, app.ykslow2_set(:,volt_idx))];
                            legend_list = [legend_list, "I_{Kslow2}"];
                        case 4
                            plot_list = [plot_list, plot(app.UIAxesModelIndv, app.t, app.ykss_set(:,volt_idx))];
                            legend_list = [legend_list, "I_{Kss}"];
                        case 5
                            plot_list = [plot_list, plot(app.UIAxesModelIndv, app.t, app.yk1_set(:,volt_idx))];
                            legend_list = [legend_list, "I_{K1}"];
                    end
                end
                hold(app.UIAxesModelIndv, "off")
                
                % legned
                legend(app.UIAxesModelIndv, legend_list, "Location","northeast")
                
                % highlighting
                if any(active_current_idx == highlight_idx)
                    set(plot_list(highlight_idx), "LineWidth",2)                
                end
            end
            
            % IKsum for one voltage
            if app.exp_idx == true
                plot(app.UIAxesModelSum, app.exp_t, app.exp_yksum_set(:,volt_idx), "LineWidth",2)
                hold(app.UIAxesModelSum, "on")
                plot(app.UIAxesModelSum, app.t, app.yksum_set(:,volt_idx), "LineWidth",2, "Color","red")
                hold(app.UIAxesModelSum, "off")
                axis(app.UIAxesModelSum, "tight")
                legend(app.UIAxesModelSum, "Experimental Data", "Model Prediction", "Location","northeast")
            else
                plot(app.UIAxesModelSum, app.t, app.yksum_set(:,volt_idx), "LineWidth",2, "Color","red")
                axis(app.UIAxesModelSum, "tight")
                legend(app.UIAxesModelSum, "Model Prediction", "Location","northeast")                
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % generate time space
            [app.t, app.hold_t, app.pulse_t] = gen_time_space(app);
            app.tt = cell(1,3);
            app.tt{1} = app.t;
            app.tt{2} = app.hold_t;
            app.tt{3} = app.pulse_t;

            % generate clamping voltages based on protocol infomation
            [app.volts, app.num_volts] = gen_volt_space(app);
            
            % voltage drop-down menu
            volt_dd_items = strings(1, app.num_volts);
            for i=1:app.num_volts
                volt_dd_items(i) = strcat(num2str(app.volts(i)), " mV");
            end
            app.SelectVoltageDropDown.Items = volt_dd_items;

            % plot default experimental protocol
            plot_protocol(app);

            % generate individual K+ currents
            app.ykto_idx = app.IncludeKto.Value;
            app.ykslow1_idx = app.IncludeKslow1.Value;
            app.ykslow2_idx = app.IncludeKslow2.Value;
            app.ykss_idx = app.IncludeKss.Value;
            app.yk1_idx = 0;
            
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            app.ykslow1_set = gen_currents_over_volts(app, @gen_ikslow1);
            app.ykslow2_set = gen_currents_over_volts(app, @gen_ikslow2);
            app.ykss_set = gen_currents_over_volts(app, @gen_ikss);
            app.yk1_set = zeros(length(app.t),app.num_volts);

            % calculate iksum
            update_sum_current(app);
            
            % plot currents
            app.highlight_idx = false;
            app.volt_idx = 1;
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            % update time space
            [app.t, app.hold_t, app.pulse_t] = gen_time_space(app);
            app.tt = cell(1,3);
            app.tt{1} = app.t;
            app.tt{2} = app.hold_t;
            app.tt{3} = app.pulse_t;
            
            % update voltages
            [app.volts, app.num_volts] = gen_volt_space(app);
            
            % voltage drop-down menu
            volt_dd_items = strings(1, app.num_volts);
            for i=1:app.num_volts
                volt_dd_items(i) = strcat(num2str(app.volts(i)), " mV");
            end
            app.SelectVoltageDropDown.Items = volt_dd_items;

            % update protocol figure
            plot_protocol(app);

            % update currents
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            app.ykslow1_set = gen_currents_over_volts(app, @gen_ikslow1);
            app.ykslow2_set = gen_currents_over_volts(app, @gen_ikslow2);
            app.ykss_set = gen_currents_over_volts(app, @gen_ikss);
            update_sum_current(app);
            
            % update currents figure
            app.volt_idx = 1;
            app.highlight_idx = false;
            app.SelectVoltageDropDown.Value = volt_dd_items(1);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end

        % Button pushed function: DatauploadButton
        function DatauploadButtonPushed(app, event)
            [file,path] = uigetfile(["*.xlsx";"*.csv"], "File Selector");
            
            % when close file selection window withou selecting file
            if (isequal(file, 0) || isequal(path, 0))
                return
            end
            
            % load experimental data
            exp_data = table2array(readtable(fullfile(path, file)));
            app.exp_t = exp_data(:,1);
            app.exp_yksum_set_orig = exp_data(:,2:end);
            
            % meta info
            app.exp_idx = true;
            [~, app.exp_num_volts] = size(app.exp_yksum_set_orig);
            
            % estimate voltages of experimental data
            min_volt = app.ClampingvoltageminmVEditField.Value;
            volt_step = app.ClampingvoltagestepmVEditField.Value;
            app.exp_volts = min_volt:volt_step:(min_volt + (app.exp_num_volts-1)*volt_step);
            
            % select data for calibration
            if app.exp_num_volts <= app.num_volts
                app.exp_yksum_set = app.exp_yksum_set_orig;
            else
                app.exp_yksum_set = app.exp_yksum_set_orig(:, 1:app.num_volts);
            end
            
            % visualize experimental data
            plot(app.UIAxesExpData, app.exp_t, app.exp_yksum_set_orig(:,1))
            hold(app.UIAxesExpData, "on")
            for i=2:app.exp_num_volts
                plot(app.UIAxesExpData, app.exp_t, app.exp_yksum_set_orig(:,i))
            end
            hold(app.UIAxesExpData, "off")
            axis(app.UIAxesExpData, "tight")
            
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);            
        end

        % Value changed function: SelectVoltageDropDown
        function SelectVoltageDropDownValueChanged(app, event)
            value = app.SelectVoltageDropDown.Value;
            volt = split(value, " ");
            volt = str2double(volt(1));
            app.volt_idx = find(app.volts == volt);
            
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);            
        end

        % Button pushed function: AutomaticCalibrationRunButton
        function AutomaticCalibrationRunButtonPushed(app, event)
            % Store original button text
            orig_button_text = app.RunButton.Text;

            % When the function ends, return the original button state
            cleanup = onCleanup(@()set(app.RunButton, 'Text',orig_button_text, 'Icon',''));

            % Change button name to "Processing"
            app.RunButton.Text = 'Processing...';
            
            % Put text on top of icon
            app.RunButton.IconAlignment = 'bottom';

            % Create waitbar with same color as button
            wbar = permute(repmat(app.RunButton.BackgroundColor,15,1,1000),[1,3,2]);

            % Black frame around waitbar
            wbar([1,end],:,:) = 0;
            wbar(:,[1,end],:) = 0;

            % Load the empty waitbar to the button
            app.RunButton.Icon = wbar;
            
            pause(0.1)
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LeftPanel = uipanel(app.UIFigure);
            app.ProtocolPanel = uipanel(app.LeftPanel);
            app.ProtocolPanel.Title = 'Experimental Protocol';
            app.UIAxesProtocol = uiaxes(app.ProtocolPanel);
            title(app.UIAxesProtocol, 'Protocol')
            xlabel(app.UIAxesProtocol, 'Time (ms)')
            ylabel(app.UIAxesProtocol, 'Clamping Voltage (mV)')
            app.HoldingtimemsEditFieldLabel = uilabel(app.ProtocolPanel);
            app.HoldingtimemsEditFieldLabel.Text = 'Holding time (ms)';
            app.HoldingtimemsEditField = uieditfield(app.ProtocolPanel, 'numeric');
            app.HoldingtimemsEditField.Value = 120;
            app.HoldingpotentialmVEditFieldLabel = uilabel(app.ProtocolPanel);
            app.HoldingpotentialmVEditFieldLabel.Text = 'Holding potential (mV)';
            app.HoldingpotentialmVEditField = uieditfield(app.ProtocolPanel, 'numeric');
            app.HoldingpotentialmVEditField.Value = -70;
            app.ClampingvoltageminmVEditFieldLabel = uilabel(app.ProtocolPanel);
            app.ClampingvoltageminmVEditFieldLabel.Text = 'Clamping voltage min (mV)';
            app.ClampingvoltageminmVEditField = uieditfield(app.ProtocolPanel, 'numeric');
            app.ClampingvoltageminmVEditField.Value = -50;
            app.ClampingtimemsEditFieldLabel = uilabel(app.ProtocolPanel);
            app.ClampingtimemsEditFieldLabel.Text = 'Clamping time (ms)';
            app.ClampingtimemsEditField = uieditfield(app.ProtocolPanel, 'numeric');
            app.ClampingtimemsEditField.ValueDisplayFormat = '%.0f';
            app.ClampingtimemsEditField.Value = 4500;
            app.NernstpotentialmVEditFieldLabel = uilabel(app.ProtocolPanel);
            app.NernstpotentialmVEditFieldLabel.Text = 'Nernst potential (mV)';
            app.NernstpotentialmVEditField = uieditfield(app.ProtocolPanel, 'numeric');
            app.NernstpotentialmVEditField.Value = -91.1;
            app.ClampingvoltagestepmVEditFieldLabel = uilabel(app.ProtocolPanel);
            app.ClampingvoltagestepmVEditFieldLabel.Text = 'Clamping voltage step (mV)';
            app.ClampingvoltagestepmVEditField = uieditfield(app.ProtocolPanel, 'numeric');
            app.ClampingvoltagestepmVEditField.Value = 10;
            app.ClampingvoltagemaxmVEditFieldLabel = uilabel(app.ProtocolPanel);
            app.ClampingvoltagemaxmVEditFieldLabel.Text = 'Clamping voltage max (mV)';
            app.ClampingvoltagemaxmVEditField = uieditfield(app.ProtocolPanel, 'numeric');
            app.ClampingvoltagemaxmVEditField.Value = 50;
            app.UpdateButton = uibutton(app.ProtocolPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.ExperimentalDataPatchclampCurrentRecordingsPanel = uipanel(app.LeftPanel);
            app.ExperimentalDataPatchclampCurrentRecordingsPanel.Title = 'Experimental Data (Patch-clamp Current Recordings)';
            app.UIAxesExpData = uiaxes(app.ExperimentalDataPatchclampCurrentRecordingsPanel);
            title(app.UIAxesExpData, 'Normalized I_{Ksum} Recordings')
            xlabel(app.UIAxesExpData, 'Time (ms)')
            ylabel(app.UIAxesExpData, 'K^{+} Current (pA/pF)')
            app.DatauploadButton = uibutton(app.ExperimentalDataPatchclampCurrentRecordingsPanel, 'push');
            app.DatauploadButton.ButtonPushedFcn = createCallbackFcn(app, @DatauploadButtonPushed, true);
            app.DatauploadButton.Text = 'Data upload';
            app.CenterPanel = uipanel(app.UIFigure);
            app.TabGroupComputerModel = uitabgroup(app.CenterPanel);
            app.ModelOutputTab = uitab(app.TabGroupComputerModel);
            app.ModelOutputTab.Title = 'Model Output';
            app.UIAxesModelSum = uiaxes(app.ModelOutputTab);
            title(app.UIAxesModelSum, 'I_{Ksum}')
            xlabel(app.UIAxesModelSum, 'X')
            ylabel(app.UIAxesModelSum, 'Y')
            zlabel(app.UIAxesModelSum, 'Z')
            app.UIAxesModelIndv = uiaxes(app.ModelOutputTab);
            title(app.UIAxesModelIndv, 'Individual K^{+} Currents')
            xlabel(app.UIAxesModelIndv, 'X')
            ylabel(app.UIAxesModelIndv, 'Y')
            zlabel(app.UIAxesModelIndv, 'Z')
            app.SelectVoltageDropDownLabel = uilabel(app.ModelOutputTab);
            app.SelectVoltageDropDownLabel.Text = 'Select Voltage';
            app.SelectVoltageDropDown = uidropdown(app.ModelOutputTab);
            app.SelectVoltageDropDown.Items = {'-50', '-40', '-30', '-20', '-10', '0', '10', '20', '30', '40', '50'};
            app.SelectVoltageDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectVoltageDropDownValueChanged, true);
            app.SelectVoltageDropDown.Value = '-50';
            app.Kv42Tab = uitab(app.TabGroupComputerModel);
            app.Kv42Tab.Title = 'Kv 4.2';
            app.IktofEq2 = uiimage(app.Kv42Tab);
            app.IktofEq2.ImageSource = 'ikto_half2.png';
            app.IktofEq1 = uiimage(app.Kv42Tab);
            app.IktofEq1.ImageSource = 'ikto_half1.png';
            app.GKtoEditField = uieditfield(app.Kv42Tab, 'numeric');
            app.GKtoEditField.Value = 0.14067;
            app.GKtopEditField = uieditfield(app.Kv42Tab, 'numeric');
            app.GKtopEditField.Value = 0.387;
            app.fKto1 = uieditfield(app.Kv42Tab, 'numeric');
            app.fKto1.Value = 0.2087704319;
            app.fKto2 = uieditfield(app.Kv42Tab, 'numeric');
            app.fKto2.Value = 0.2087704319;
            app.p1KtoEditField1 = uieditfield(app.Kv42Tab, 'numeric');
            app.p1KtoEditField1.Value = 33;
            app.p1KtoEditField2 = uieditfield(app.Kv42Tab, 'numeric');
            app.p1KtoEditField2.Value = 33;
            app.p2KtoEditField = uieditfield(app.Kv42Tab, 'numeric');
            app.p2KtoEditField.Value = 15.5;
            app.p2p3KtoEditField1 = uieditfield(app.Kv42Tab, 'numeric');
            app.p2p3KtoEditField1.Value = 35.5;
            app.p2p3KtoEditField2 = uieditfield(app.Kv42Tab, 'numeric');
            app.p2p3KtoEditField2.Value = 35.5;
            app.p2p3KtoEditField3 = uieditfield(app.Kv42Tab, 'numeric');
            app.p2p3KtoEditField3.Value = 35.5;
            app.p1p4KtoEditField1 = uieditfield(app.Kv42Tab, 'numeric');
            app.p1p4KtoEditField1.Value = 17;
            app.p1p4KtoEditField2 = uieditfield(app.Kv42Tab, 'numeric');
            app.p1p4KtoEditField2.Value = 17;
            app.p2p5KtoEditField = uieditfield(app.Kv42Tab, 'numeric');
            app.p2p5KtoEditField.Value = 7.5;
            app.p6KtoEditField1 = uieditfield(app.Kv42Tab, 'numeric');
            app.p6KtoEditField1.Value = 7;
            app.p6KtoEditField2 = uieditfield(app.Kv42Tab, 'numeric');
            app.p6KtoEditField2.Value = 7;
            app.p6KtoEditField3 = uieditfield(app.Kv42Tab, 'numeric');
            app.p6KtoEditField3.Value = 7;
            app.p6KtoEditField4 = uieditfield(app.Kv42Tab, 'numeric');
            app.p6KtoEditField4.Value = 7;
            app.p6KtoEditField5 = uieditfield(app.Kv42Tab, 'numeric');
            app.p6KtoEditField5.Value = 7;
            app.p6KtoEditField6 = uieditfield(app.Kv42Tab, 'numeric');
            app.p6KtoEditField6.Value = 7;
            app.p6KtoEditField7 = uieditfield(app.Kv42Tab, 'numeric');
            app.p6KtoEditField7.Value = 7;
            app.p6EditField_8 = uieditfield(app.Kv42Tab, 'numeric');
            app.p6EditField_8.Value = 7;
            app.p7KtoEditField1 = uieditfield(app.Kv42Tab, 'numeric');
            app.p7KtoEditField1.Value = 0.03577;
            app.p7KtoEditField2 = uieditfield(app.Kv42Tab, 'numeric');
            app.p7KtoEditField2.Value = 0.03577;
            app.p8KtoEditField1 = uieditfield(app.Kv42Tab, 'numeric');
            app.p8KtoEditField1.Value = 0.06237;
            app.p8KtoEditField2 = uieditfield(app.Kv42Tab, 'numeric');
            app.p8KtoEditField2.Value = 0.06237;
            app.p9KtoEditField1 = uieditfield(app.Kv42Tab, 'numeric');
            app.p9KtoEditField1.ValueDisplayFormat = '%11.6g';
            app.p9KtoEditField1.Value = 0.18064;
            app.p9KtoEditField2 = uieditfield(app.Kv42Tab, 'numeric');
            app.p9KtoEditField2.ValueDisplayFormat = '%11.6g';
            app.p9KtoEditField2.Value = 0.18064;
            app.p10KtoEditField1 = uieditfield(app.Kv42Tab, 'numeric');
            app.p10KtoEditField1.ValueDisplayFormat = '%11.6g';
            app.p10KtoEditField1.Value = 0.3956;
            app.p10KtoEditField2 = uieditfield(app.Kv42Tab, 'numeric');
            app.p10KtoEditField2.ValueDisplayFormat = '%11.5g';
            app.p10KtoEditField2.Value = 0.3956;
            app.p11KtoEditField1 = uieditfield(app.Kv42Tab, 'numeric');
            app.p11KtoEditField1.ValueDisplayFormat = '%11.5g';
            app.p11KtoEditField1.Value = 0.000152;
            app.p11KtoEditField2 = uieditfield(app.Kv42Tab, 'numeric');
            app.p11KtoEditField2.ValueDisplayFormat = '%11.8g';
            app.p11KtoEditField2.Value = 0.000152;
            app.p12KtoEditField1 = uieditfield(app.Kv42Tab, 'numeric');
            app.p12KtoEditField1.ValueDisplayFormat = '%11.7g';
            app.p12KtoEditField1.Value = 0.067083;
            app.p12KtoEditField2 = uieditfield(app.Kv42Tab, 'numeric');
            app.p12KtoEditField2.ValueDisplayFormat = '%11.7g';
            app.p12KtoEditField2.Value = 0.067083;
            app.p13KtoEditField1 = uieditfield(app.Kv42Tab, 'numeric');
            app.p13KtoEditField1.ValueDisplayFormat = '%11.8g';
            app.p13KtoEditField1.Value = 0.00095;
            app.p13KtoEditField2 = uieditfield(app.Kv42Tab, 'numeric');
            app.p13KtoEditField2.ValueDisplayFormat = '%11.8g';
            app.p13KtoEditField2.Value = 0.00095;
            app.p14KtoEditField1 = uieditfield(app.Kv42Tab, 'numeric');
            app.p14KtoEditField1.ValueDisplayFormat = '%11.8g';
            app.p14KtoEditField1.Value = 0.051335;
            app.p14KtoEditField2 = uieditfield(app.Kv42Tab, 'numeric');
            app.p14KtoEditField2.ValueDisplayFormat = '%11.8g';
            app.p14KtoEditField2.Value = 0.051335;
            app.p2p3p5KtoEditField1 = uieditfield(app.Kv42Tab, 'numeric');
            app.p2p3p5KtoEditField1.ValueDisplayFormat = '%11.3g';
            app.p2p3p5KtoEditField1.Value = 27.5;
            app.p2p3p5KtoEditField2 = uieditfield(app.Kv42Tab, 'numeric');
            app.p2p3p5KtoEditField2.ValueDisplayFormat = '%11.3g';
            app.p2p3p5KtoEditField2.Value = 27.5;
            app.p2p3p5KtoEditField3 = uieditfield(app.Kv42Tab, 'numeric');
            app.p2p3p5KtoEditField3.ValueDisplayFormat = '%11.3g';
            app.p2p3p5KtoEditField3.Value = 27.5;
            app.LabelModel1 = uilabel(app.Kv42Tab);
            app.LabelModel1.Text = 'IKto,f (Kv 4.2): Transient outward K+ current';
            app.IncludeKto = uicheckbox(app.Kv42Tab);
            app.IncludeKto.Text = 'Include';
            app.IncludeKto.Value = true;
            app.Kv15Kv21K2PTab = uitab(app.TabGroupComputerModel);
            app.Kv15Kv21K2PTab.Title = 'Kv 1.5 / Kv 2.1 / K2P';
            app.Ikslow1Eq1 = uiimage(app.Kv15Kv21K2PTab);
            app.Ikslow1Eq1.ImageSource = 'ikslow1_half1.png';
            app.Ikslow1Eq2 = uiimage(app.Kv15Kv21K2PTab);
            app.Ikslow1Eq2.ImageSource = 'ikslow1_half2.png';
            app.p1Kslow1EditField = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p1Kslow1EditField.Limits = [-70 50];
            app.p1Kslow1EditField.Value = 22.5;
            app.p2Kslow1EditField1 = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p2Kslow1EditField1.Limits = [-70 50];
            app.p2Kslow1EditField1.Value = 45.2;
            app.p2Kslow1EditField2 = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p2Kslow1EditField2.Limits = [-70 50];
            app.p2Kslow1EditField2.Value = 45.2;
            app.p3Kslow1EditField1 = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p3Kslow1EditField1.Limits = [-70 50];
            app.p3Kslow1EditField1.Value = 40;
            app.p3Kslow1EditField2 = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p3Kslow1EditField2.Limits = [-70 50];
            app.p3Kslow1EditField2.Value = 40;
            app.p4Kslow1EditField = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p4Kslow1EditField.Limits = [2.2204e-16 10];
            app.p4Kslow1EditField.Value = 7.7;
            app.p5Kslow1EditField1 = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p5Kslow1EditField1.Limits = [2.2204e-16 50];
            app.p5Kslow1EditField1.Value = 5.7;
            app.p5Kslow1EditField2 = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p5Kslow1EditField2.Limits = [2.2204e-16 50];
            app.p5Kslow1EditField2.Value = 5.7;
            app.p6Kslow1EditField = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p6Kslow1EditField.Limits = [2.2204e-16 50];
            app.p6Kslow1EditField.Value = 6.1;
            app.p7Kslow1EditField1 = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p7Kslow1EditField1.Limits = [2.2204e-16 1];
            app.p7Kslow1EditField1.Value = 0.0629;
            app.p7Kslow1EditField2 = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p7Kslow1EditField2.Limits = [2.2204e-16 1];
            app.p7Kslow1EditField2.Value = 0.0629;
            app.p8Kslow1EditField = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p8Kslow1EditField.Limits = [2.2204e-16 100];
            app.p8Kslow1EditField.Value = 2.058;
            app.p9Kslow1EditField = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p9Kslow1EditField.Limits = [2.2204e-16 1000];
            app.p9Kslow1EditField.Value = 803;
            app.p10Kslow1EditField = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p10Kslow1EditField.Limits = [2.2204e-16 50];
            app.p10Kslow1EditField.Value = 18;
            app.p11Kslow1EditField1 = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p11Kslow1EditField1.Limits = [2.2204e-16 1];
            app.p11Kslow1EditField1.Value = 0.9214774521;
            app.p11Kslow1EditField2 = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p11Kslow1EditField2.Limits = [2.2204e-16 1];
            app.p11Kslow1EditField2.Value = 0.9214774521;
            app.p12Kslow1EditField = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p12Kslow1EditField.Limits = [2.2204e-16 0.5];
            app.p12Kslow1EditField.Value = 0.05766;
            app.p13Kslow1EditField = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p13Kslow1EditField.Limits = [2.2204e-16 0.5];
            app.p13Kslow1EditField.Value = 0.07496;
            app.Ikslow2Eq = uiimage(app.Kv15Kv21K2PTab);
            app.Ikslow2Eq.ImageSource = 'ikslow2.png';
            app.p1Kslow2EditField = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p1Kslow2EditField.Limits = [5000 10000];
            app.p1Kslow2EditField.Value = 5334;
            app.p2Kslow2EditField = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p2Kslow2EditField.Limits = [0 10000];
            app.p2Kslow2EditField.Value = 4912;
            app.p3Kslow2EditField = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p3Kslow2EditField.Limits = [0 0.5];
            app.p3Kslow2EditField.Value = 0.05766;
            app.LabelModel21 = uilabel(app.Kv15Kv21K2PTab);
            app.LabelModel21.Text = 'IKslow1 (Kv 1.5): Rapidly activating, slowly inactivating rectifier K+ current 1';
            app.LabelModel22 = uilabel(app.Kv15Kv21K2PTab);
            app.LabelModel22.Text = 'IKslow2 (Kv 2.1): Rapidly activating, slowly inactivating rectifier K+ current 2';
            app.LabelModel23 = uilabel(app.Kv15Kv21K2PTab);
            app.LabelModel23.Text = 'IKss (K2P): Non-inactivating steady-state K+ current';
            app.Image = uiimage(app.Kv15Kv21K2PTab);
            app.Image.ImageSource = 'ikss.png';
            app.p1KssEditField1 = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p1KssEditField1.Limits = [0 1];
            app.p1KssEditField1.Value = 0.0862;
            app.p1KssEditField2 = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p1KssEditField2.Limits = [0 1];
            app.p1KssEditField2.Value = 0.0862;
            app.p2KssEditField = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p2KssEditField.Limits = [0 2000];
            app.p2KssEditField.Value = 1235.5;
            app.p3KssEditField = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p3KssEditField.Limits = [0 100];
            app.p3KssEditField.Value = 13.17;
            app.p4KssEditField = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p4KssEditField.Limits = [0 0.5];
            app.p4KssEditField.Value = 0.0428;
            app.p3Kslow1EditField3 = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p3Kslow1EditField3.Limits = [-70 50];
            app.p3Kslow1EditField3.Value = 40;
            app.p3Kslow1EditField4 = uieditfield(app.Kv15Kv21K2PTab, 'numeric');
            app.p3Kslow1EditField4.Limits = [-70 50];
            app.p3Kslow1EditField4.Value = 40;
            app.IncludeKslow1 = uicheckbox(app.Kv15Kv21K2PTab);
            app.IncludeKslow1.Text = 'Include';
            app.IncludeKslow1.Value = true;
            app.IncludeKslow2 = uicheckbox(app.Kv15Kv21K2PTab);
            app.IncludeKslow2.Text = 'Include';
            app.IncludeKslow2.Value = true;
            app.IncludeKss = uicheckbox(app.Kv15Kv21K2PTab);
            app.IncludeKss.Text = 'Include';
            app.IncludeKss.Value = true;
            app.RightPanel = uipanel(app.UIFigure);
            app.TabGroupCalibration = uitabgroup(app.RightPanel);
            app.ModelCalibrationTab = uitab(app.TabGroupCalibration);
            app.ModelCalibrationTab.Title = 'Model Calibration';
            app.AutomaticCalibrationRunButton = uibutton(app.ModelCalibrationTab, 'push');
            app.AutomaticCalibrationRunButton.ButtonPushedFcn = createCallbackFcn(app, @AutomaticCalibrationRunButtonPushed, true);
            app.AutomaticCalibrationRunButton.Text = 'Automatic Calibration Run';
            app.ManualCalibrationButton = uibutton(app.ModelCalibrationTab, 'push');
            app.ManualCalibrationButton.Text = 'Manual Calibration';
            app.ModelParametersSaveButton = uibutton(app.ModelCalibrationTab, 'push');
            app.ModelParametersSaveButton.Text = 'Model Parameters Save';
            app.ModelParametersImportButton = uibutton(app.ModelCalibrationTab, 'push');
            app.ModelParametersImportButton.Text = 'Model Parameters Import';
            app.Kv42TabCalib = uitab(app.TabGroupCalibration);
            app.Kv42TabCalib.Title = 'Kv 4.2';
            app.Kv15Kv21K2PTabCalib = uitab(app.TabGroupCalibration);
            app.Kv15Kv21K2PTabCalib.Title = 'Kv 1.5 / Kv 2.1 / K2P';
            app.ExperimentalProtocolDataLabel = uilabel(app.UIFigure);
            app.ExperimentalProtocolDataLabel.Text = 'Experimental Protocol & Data';
            app.ComputerModelLabel = uilabel(app.UIFigure);
            app.ComputerModelLabel.Text = 'Computer Model';
            app.ModelCalibrationKineticsModelingResultsLabel = uilabel(app.UIFigure);
            app.ModelCalibrationKineticsModelingResultsLabel.Text = 'Model Calibration & Kinetics Modeling Results';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Kv

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end